classdef CVsimapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CVplot                         matlab.ui.control.UIAxes
        Concentrationmolcm3EditFieldLabel  matlab.ui.control.Label
        Cfield                         matlab.ui.control.NumericEditField
        Diffusioncoefficientcm2sEditFieldLabel  matlab.ui.control.Label
        Dfield                         matlab.ui.control.NumericEditField
        InitialoverpotentialVEditFieldLabel  matlab.ui.control.Label
        etaifield                      matlab.ui.control.NumericEditField
        FinaloverpotentialVEditFieldLabel  matlab.ui.control.Label
        etaffield                      matlab.ui.control.NumericEditField
        ScanrateVsEditFieldLabel       matlab.ui.control.Label
        vfield                         matlab.ui.control.NumericEditField
        ChargetransfercoefficientEditFieldLabel  matlab.ui.control.Label
        alphafield                     matlab.ui.control.NumericEditField
        Electrochemicalrateconstantk0cmsLabel  matlab.ui.control.Label
        k0field                        matlab.ui.control.NumericEditField
        Chemicalrateconstantkc1sLabel  matlab.ui.control.Label
        k1field                        matlab.ui.control.NumericEditField
        PlotButton                     matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        LegendEditFieldLabel           matlab.ui.control.Label
        Legend                         matlab.ui.control.EditField
        CyclicVoltammetrySimulatorLabel  matlab.ui.control.Label
        OpenButton                     matlab.ui.control.Button
        OeRZLabel                      matlab.ui.control.Label
        PeterAttia2017Label            matlab.ui.control.Label
        kLabel_3                       matlab.ui.control.Label
        cLabel                         matlab.ui.control.Label
        LambdaLabel                    matlab.ui.control.Label
        LambdaField                    matlab.ui.control.NumericEditField
        concplot                       matlab.ui.control.UIAxes
        TimestepSliderLabel            matlab.ui.control.Label
        TimestepSlider                 matlab.ui.control.Slider
        PotentialVLabel                matlab.ui.control.Label
        EtaField                       matlab.ui.control.NumericEditField
        kLabel_4                       matlab.ui.control.Label
        fLabel                         matlab.ui.control.Label
        kLabel_5                       matlab.ui.control.Label
        rLabel                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        legendlabels; % labels for all legends
        Avals;
        Bvals;
        Xvals;
        Lval;
        DMval;
        etaval;
    end
    
    properties (Access = private)
        numplots = 0; % number of current plots
        XData; % Description
        YData; % Description
    end
    
    methods (Access = private)
        
        function [eta, Z, Lambda] = generateCVcurve(app)
            %%%%%
            % This simulation is based on Bard and Faulkner, 2nd edition, Appendix B.
            %%%%%
            
            
            %%% USER-CONTROLLED VARIABLES %%%
            C = app.Cfield.Value;
            D = app.Dfield.Value;
            etai = app.etaifield.Value;
            etaf = app.etaffield.Value;
            v = app.vfield.Value;
            alpha = app.alphafield.Value;
            k0 = app.k0field.Value;
            k1 = app.k1field.Value;
            
            %%% SIMULATION VARIABLES %%%
            L      = 500;    % [=] number of iterations per t_k (pg 790). Default = 500
            DM     = 0.45;   % [=] model diffusion coefficient (pg 788). Default = 0.45
            
            %%% CONSTANTS %%%
            F      = 96485;   % [=] C/mol, Faraday's constant
            R      = 8.3145;  % [=] J/mol-K, ideal gas constant
            T      = 298.15;  % [=] K, temperature. Default = 298.15
            f      = F/(R*T); % [=] dimensionless Faraday's constant at room temperature
            
            %%% DERIVED CONSTANTS %%%
            j      = ceil(4.2*L^0.5)+5;   % [=] number of boxes. If L~200, j=65
            Deta   = (etai-etaf)/v;       % [=] s, time of one scan (pg 790)
            tk     = 2*Deta;              % [=] s, characteristic exp. time (pg 790). In this case, total time of fwd and rev scans
            Dt     = tk/L;                % [=] s, delta time (Eqn B.1.10, pg 790)
            Dx     = sqrt(D*Dt/DM);       % [=] cm, delta x (Eqn B.1.13, pg 791)
            ktk    = k1*tk;               % [=] dimensionless kinetic parameter (Eqn B.3.7, pg 797)
            km     = ktk/L;               % [=] normalized dimensionless kinetic parameter (see bottom of pg 797)
            Lambda = k0/(D*f*v)^0.5;      % [=] dimensionless reversibility parameter (Eqn 6.4.4, pg. 236-239)
            
            if km>0.1
                warning(['k_c*t_k/l equals ' num2str(km)...
                    ', which exceeds the upper limit of 0.1 (see B&F, pg 797)'])
            end
            
            %%% START SIMULATION %%%
            k = 0:L;
            t = Dt * k;
            X = (k+1)/sqrt(L*DM);
            eta1 = etai - v*t; % negative scan
            eta2 = etaf + v*t; % positive scan
            eta = [eta1(eta1>etaf) eta2(eta2<=etai)]'; % eta includes both fwd and rev
            Enorm = eta*f;
            kf = ( k0*tk*exp(  -alpha *Enorm) )./( sqrt(DM*L)*Dx );
            kb = ( k0*tk*exp((1-alpha)*Enorm) )./( sqrt(DM*L)*Dx );
            
            A = C*ones(length(k)-1,j);
            B = zeros(length(k)-1,j);
            Z = zeros(length(k)-1,1);
            
            Z(1) = ( kf(1)*A(1,2) - kb(1)*B(1,2) ) ./ (1 + kf(1) + kb(1));
            
            for i1 = 2:length(k)-1
                Z(i1)   = ( kf(i1).*A(i1,2) - kb(i1).*B(i1,2) ) ./ (1 + kf(i1) + kb(i1));
                A(i1,1) = A(i1,2) - Z(i1) + km*B(i1-1,1);
                B(i1,1) = B(i1,2) + Z(i1) - km*B(i1-1,1);
                
                for i2 = 2:j-1
                    A(i1,i2) = A(i1-1,i2) + DM*(A(i1-1,i2+1)+A(i1-1,i2-1)-2*A(i1-1,i2)) + ...
                        + km * B(i1-1,i2);
                    
                    B(i1,i2) = B(i1-1,i2) + DM*(B(i1-1,i2+1)+B(i1-1,i2-1)-2*B(i1-1,i2)) + ...
                        - km * B(i1-1,i2);
                    
                end
                
                Z(i1)   = ( kf(i1).*A(i1,2) - kb(i1).*B(i1,2) ) ./ (1 + kf(i1) + kb(i1));
                
            end
            eta = eta(1:end-1);
            Z = Z(1:end-1);
            
            % Update global variables
            app.Avals = A;
            app.Bvals = B;
            app.Xvals = X;
            app.Lval = L;
            app.DMval = DM;
            app.etaval = eta;
        end
        
        function [] = setdefaults(app)
            % Set plot defaults to look pretty
            set(0, 'DefaultLineLineWidth',2)
            set(0, 'DefaultAxesFontSize',18)
            set(0, 'DefaultAxesXMinorTick','on')
            set(0, 'DefaultAxesYMinorTick','on')
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Reset global variables
            app.numplots = 0;
            app.legendlabels = {};
            app.XData = {};
            app.YData = {};
            app.LambdaField.Value = 0;
            app.EtaField.Value = 0;
            app.Avals = [];
            app.Bvals = [];
            
            % Reset CV plot
            hold(app.CVplot, 'off')
            cla(app.CVplot)
            legend(app.CVplot,'hide')
            
            % Reset concentration plot
            cla(app.concplot)
            legend(app.concplot,'hide')
            app.TimestepSlider.Value = 0;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            %% Uncomment to enable saving
            %[FileName, PathName] = uiputfile;
            %saveDataName = fullfile(PathName, FileName);
            
            app.setdefaults();
            
            figure, hold on, box on
            for i = 1:app.numplots
                plot(app.XData{i},-app.YData{i}) % Bard & Faulkner current convention is silly
            end
            xlabel('Overpotential (V)'),ylabel('Dimensionless current')
            legend(app.legendlabels,'location','southeast')
            
            %% Uncomment to enable saving
            % saveas(plt,[saveDataName(1:end-4),'.fig']);
            % close()
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            [eta, Z, Lambda] = generateCVcurve(app);
            
            if length(eta) ~= length(Z)
                eta = eta(1:end-1);
            end
            
            % Update global variables with results
            app.numplots = app.numplots + 1;
            app.legendlabels{app.numplots} = app.Legend.Value;
            app.XData{app.numplots} = eta;
            app.YData{app.numplots} = Z;
            
            % Update static displays
            app.LambdaField.Value = Lambda;
            app.EtaField.Value = app.etaifield.Value;
            
            % Update timestep slider
            app.TimestepSlider.Value = 0;
            app.TimestepSlider.Limits = [0, app.Lval];
            
            % Plot I-V data 
            hold(app.CVplot, 'on')
            plot(app.CVplot,eta,-Z)
            legend(app.CVplot,app.legendlabels,'location','southeast')
            
            % Plot concentration data
            cla(app.concplot)
            hold(app.concplot, 'on')
            dimlessdistance = ((1:length(app.Avals(1,:))) - 1) ./ (app.Lval * app.DMval)^0.5; %(Eqn B.1.15)
            plot(app.concplot,dimlessdistance,app.Avals(1,:))
            plot(app.concplot,dimlessdistance,app.Bvals(1,:))
            legend(app.concplot,{'O','R'},'location','southeast')
        end

        % Value changing function: TimestepSlider
        function SliderChanging(app, event)
            changingValue = round(event.Value)+1;
            if changingValue == app.Lval + 1, changingValue = app.Lval; end
            if app.numplots > 0
                % Concentration plot
                cla(app.concplot)
                hold(app.concplot, 'on')
                dimlessdistance = ((1:length(app.Avals(1,:))) - 1) ./ (app.Lval * app.DMval)^0.5; %(Eqn B.1.15)
                plot(app.concplot,dimlessdistance,app.Avals(changingValue,:))
                plot(app.concplot,dimlessdistance,app.Bvals(changingValue,:))
                legend(app.concplot,{'O','R'},'location','southeast') 
                
                % Update potential
                app.EtaField.Value = app.etaval(changingValue);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CVplot = uiaxes(app.UIFigure);
            xlabel(app.CVplot, 'Overpotential (V)')
            ylabel(app.CVplot, 'Dimensionless current')
            app.Concentrationmolcm3EditFieldLabel = uilabel(app.UIFigure);
            app.Concentrationmolcm3EditFieldLabel.Text = 'Concentration (mol/cm^3)';
            app.Cfield = uieditfield(app.UIFigure, 'numeric');
            app.Cfield.Limits = [0 10];
            app.Cfield.ValueDisplayFormat = '%.2f';
            app.Cfield.Value = 1;
            app.Diffusioncoefficientcm2sEditFieldLabel = uilabel(app.UIFigure);
            app.Diffusioncoefficientcm2sEditFieldLabel.Text = 'Diffusion coefficient (cm^2/s)';
            app.Dfield = uieditfield(app.UIFigure, 'numeric');
            app.Dfield.Limits = [0 100000];
            app.Dfield.ValueDisplayFormat = '%8.2E';
            app.Dfield.Value = 1e-05;
            app.InitialoverpotentialVEditFieldLabel = uilabel(app.UIFigure);
            app.InitialoverpotentialVEditFieldLabel.Text = 'Initial overpotential (V)';
            app.etaifield = uieditfield(app.UIFigure, 'numeric');
            app.etaifield.Limits = [0 10];
            app.etaifield.ValueDisplayFormat = '%.2f';
            app.etaifield.Value = 0.2;
            app.FinaloverpotentialVEditFieldLabel = uilabel(app.UIFigure);
            app.FinaloverpotentialVEditFieldLabel.Text = 'Final overpotential (V)';
            app.etaffield = uieditfield(app.UIFigure, 'numeric');
            app.etaffield.Limits = [-10 0];
            app.etaffield.ValueDisplayFormat = '%.2f';
            app.etaffield.Value = -0.2;
            app.ScanrateVsEditFieldLabel = uilabel(app.UIFigure);
            app.ScanrateVsEditFieldLabel.Text = 'Scan rate (V/s)';
            app.vfield = uieditfield(app.UIFigure, 'numeric');
            app.vfield.Limits = [0 100000];
            app.vfield.ValueDisplayFormat = '%8.2E';
            app.vfield.Value = 0.01;
            app.ChargetransfercoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.ChargetransfercoefficientEditFieldLabel.Text = 'Charge transfer coefficient';
            app.alphafield = uieditfield(app.UIFigure, 'numeric');
            app.alphafield.Limits = [0 1];
            app.alphafield.ValueDisplayFormat = '%.2f';
            app.alphafield.Value = 0.5;
            app.Electrochemicalrateconstantk0cmsLabel = uilabel(app.UIFigure);
            app.Electrochemicalrateconstantk0cmsLabel.Text = 'Electrochemical rate constant, k0 (cm/s)';
            app.k0field = uieditfield(app.UIFigure, 'numeric');
            app.k0field.Limits = [0 10000];
            app.k0field.ValueDisplayFormat = '%8.2E';
            app.k0field.Value = 1;
            app.Chemicalrateconstantkc1sLabel = uilabel(app.UIFigure);
            app.Chemicalrateconstantkc1sLabel.Text = 'Chemical rate constant, kc (1/s) ';
            app.k1field = uieditfield(app.UIFigure, 'numeric');
            app.k1field.Limits = [0 1000000];
            app.k1field.ValueDisplayFormat = '%8.2E';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.LegendEditFieldLabel = uilabel(app.UIFigure);
            app.LegendEditFieldLabel.Text = 'Legend';
            app.Legend = uieditfield(app.UIFigure, 'text');
            app.CyclicVoltammetrySimulatorLabel = uilabel(app.UIFigure);
            app.CyclicVoltammetrySimulatorLabel.Text = 'Cyclic Voltammetry Simulator';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.OeRZLabel = uilabel(app.UIFigure);
            app.OeRZLabel.Text = 'O + e- <-> R -> Z';
            app.PeterAttia2017Label = uilabel(app.UIFigure);
            app.PeterAttia2017Label.Text = 'Peter Attia, 2017';
            app.kLabel_3 = uilabel(app.UIFigure);
            app.kLabel_3.Text = 'k';
            app.cLabel = uilabel(app.UIFigure);
            app.cLabel.Text = 'c';
            app.LambdaLabel = uilabel(app.UIFigure);
            app.LambdaLabel.Text = 'Lambda = ';
            app.LambdaField = uieditfield(app.UIFigure, 'numeric');
            app.LambdaField.ValueDisplayFormat = '%11.3g';
            app.LambdaField.Editable = 'off';
            app.concplot = uiaxes(app.UIFigure);
            xlabel(app.concplot, 'Dimensionless distance')
            ylabel(app.concplot, 'Concentration (mol/cm^3)')
            app.TimestepSliderLabel = uilabel(app.UIFigure);
            app.TimestepSliderLabel.Text = 'Timestep';
            app.TimestepSlider = uislider(app.UIFigure);
            app.TimestepSlider.Limits = [0 500];
            app.TimestepSlider.ValueChangingFcn = createCallbackFcn(app, @SliderChanging, true);
            app.PotentialVLabel = uilabel(app.UIFigure);
            app.PotentialVLabel.Text = 'Potential (V)= ';
            app.EtaField = uieditfield(app.UIFigure, 'numeric');
            app.EtaField.ValueDisplayFormat = '%11.3g';
            app.EtaField.Editable = 'off';
            app.kLabel_4 = uilabel(app.UIFigure);
            app.kLabel_4.Text = 'k';
            app.fLabel = uilabel(app.UIFigure);
            app.fLabel.Text = 'f';
            app.kLabel_5 = uilabel(app.UIFigure);
            app.kLabel_5.Text = 'k';
            app.rLabel = uilabel(app.UIFigure);
            app.rLabel.Text = 'r';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CVsimapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end